classdef move_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button                    matlab.ui.control.Button
        yplus                     matlab.ui.control.Button
        yminus                    matlab.ui.control.Button
        xminusButton              matlab.ui.control.Button
        xplusButton               matlab.ui.control.Button
        RobotSelectDropDownLabel  matlab.ui.control.Label
        RobotSelectDropDown       matlab.ui.control.DropDown
        zminus                    matlab.ui.control.Button
        zplus                     matlab.ui.control.Button
        ERESETButton              matlab.ui.control.Button
        Q1SliderLabel             matlab.ui.control.Label
        Q1Slider                  matlab.ui.control.Slider
        Q2SliderLabel             matlab.ui.control.Label
        Q2Slider                  matlab.ui.control.Slider
    end

    
    properties (Access = public)
        StopCheck = false;
        UR3
        KUKA
        RobotSelect = 'UR3'
        reset = false;
        qslider = [0 0 0 0 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.StopCheck == false
                app.StopCheck = true;
            elseif app.reset == true
                app.StopCheck = false;
                app.reset = false;
            end
        end

        % Button pushed function: xplusButton
        function xplusButtonPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(1,4) = tr(1,4)+0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(1,4) = tr(1,4)+0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                end
                
            end
        end

        % Button pushed function: xminusButton
        function xminusButtonPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(1,4) = tr(1,4)-0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(1,4) = tr(1,4)-0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                end
                
            end
        end

        % Button pushed function: yplus
        function yplusPushed(app, event)
              if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(2,4) = tr(2,4)+0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(2,4) = tr(2,4)+0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                end
                
              end
        end

        % Button pushed function: yminus
        function yminusPushed(app, event)
          if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(2,4) = tr(2,4)-0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(2,4) = tr(2,4)-0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                end
                
          end
        end

        % Drop down opening function: RobotSelectDropDown
        function RobotSelectDropDownOpening(app, event)
            
        end

        % Value changed function: RobotSelectDropDown
        function RobotSelectDropDownValueChanged(app, event)
            app.RobotSelect = app.RobotSelectDropDown.Value;
          
        end

        % Button pushed function: zplus
        function zplusButtonPushed(app, event)
               if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(3,4) = tr(3,4)+0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(3,4) = tr(3,4)+0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                end
                
              end
        end

        % Button pushed function: zminus
        function zminusButtonPushed(app, event)
               if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(3,4) = tr(3,4)-0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(3,4) = tr(3,4)-0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                end
                
              end
        end

        % Button pushed function: ERESETButton
        function ERESETButtonPushed(app, event)
            if app.StopCheck == true
            app.reset = true;
            end
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q1slide = app.Q1Slider.Value/100;
                        q1 = app.KUKA.qlim(1,1)+q1slide*(app.KUKA.qlim(1,2)-app.KUKA.qlim(1,1));
                        q = app.KUKA.getpos;
                        q(1,1) = q1;
                        app.KUKA.animate(q);
                    case 'UR3'
                        q1slide = app.Q1Slider.Value/100;
                        q1 = app.UR3.qlim(1,1)+q1slide*(app.UR3.qlim(1,2)-app.UR3.qlim(1,1));
                        q = app.UR3.getpos;
                        q(1,1) = q1;
                        app.UR3.animate(q);
                end
            end
                
                
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
            q2slide = app.Q2Slider.Value/100;
            q2 = app.KUKA.qlim(2,1)+q2slide*(app.KUKA.qlim(2,2)-app.KUKA.qlim(2,1));
            q = app.KUKA.getpos;
            q(1,2) = q2;
            app.KUKA.animate(q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'ESTOP.jpg';
            app.Button.Text = '';
            app.yplus = uibutton(app.UIFigure, 'push');
            app.yplus.ButtonPushedFcn = createCallbackFcn(app, @yplusPushed, true);
            app.yplus.Text = '+y';
            app.yminus = uibutton(app.UIFigure, 'push');
            app.yminus.ButtonPushedFcn = createCallbackFcn(app, @yminusPushed, true);
            app.yminus.Text = '-y';
            app.xminusButton = uibutton(app.UIFigure, 'push');
            app.xminusButton.ButtonPushedFcn = createCallbackFcn(app, @xminusButtonPushed, true);
            app.xminusButton.Text = {'-x'; ''};
            app.xplusButton = uibutton(app.UIFigure, 'push');
            app.xplusButton.ButtonPushedFcn = createCallbackFcn(app, @xplusButtonPushed, true);
            app.xplusButton.Text = '+x';
            app.RobotSelectDropDownLabel = uilabel(app.UIFigure);
            app.RobotSelectDropDownLabel.Text = 'Robot Select';
            app.RobotSelectDropDown = uidropdown(app.UIFigure);
            app.RobotSelectDropDown.Items = {'UR3', 'KUKA'};
            app.RobotSelectDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RobotSelectDropDownOpening, true);
            app.RobotSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotSelectDropDownValueChanged, true);
            app.RobotSelectDropDown.Value = 'UR3';
            app.zminus = uibutton(app.UIFigure, 'push');
            app.zminus.ButtonPushedFcn = createCallbackFcn(app, @zminusButtonPushed, true);
            app.zminus.Text = '-z';
            app.zplus = uibutton(app.UIFigure, 'push');
            app.zplus.ButtonPushedFcn = createCallbackFcn(app, @zplusButtonPushed, true);
            app.zplus.Text = '+z';
            app.ERESETButton = uibutton(app.UIFigure, 'push');
            app.ERESETButton.ButtonPushedFcn = createCallbackFcn(app, @ERESETButtonPushed, true);
            app.ERESETButton.Text = 'E-RESET';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1 Slider';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.MajorTicks = [0 100];
            app.Q1Slider.MajorTickLabels = {'0%', '100%'};
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2 Slider';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.MajorTicks = [0 100];
            app.Q2Slider.MajorTickLabels = {'0%', '100%'};
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = move_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end